classdef TimeBar < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Draw  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ButtonTrack (1,1) logical = false; % Flag, if tracking mouse button/motion
        Figure % Root figure handle
        TimeHit (1,1) % Button press time
        WindowButtonDownTic % Result of 'tic()' at button press
        WindowButtonMotionFcn % Backup callback
        WindowButtonUpFcn % Backup callback
    end
    
    properties (Access = protected)
        Time (1,2) = [0 1]; % Limits for x-axis (time).
    end
    
    
    methods (Hidden)
        function onMouseMotion(comp,src,ev,input)
            if input.Hit ~= comp.Draw
                disp(input.Hit)
            end
        end

        function onMouseButton(comp,src,ev,input)
            %comp.Draw.CurrentPoint(1,1) 
            %comp.WindowButtonDownTic = tic();
            %dt = toc(comp.WindowButtonDownTic); % time between down and up
            disp(input)
        end

        function onKeyPress(comp,src,ev,input)
            switch ev.Key
                case 'escape'
                    % FIXME
            end
        end
    end

    methods (Access = public)
        
        function results = setTime(comp,t)
            %SETTIME    Set limits of the time axis.
            %
            % 
            comp.Time = t;
            set(comp.Draw,XLim = t);
        end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            axis(comp.Draw,'tight');
            ana.ui.input(comp.Draw,"MouseMotion",@comp.onMouseMotion,Hover=true);
            ana.ui.input(comp.Draw,"MousePress",@comp.onMouseButton);
            ana.ui.input(comp.Draw,"MouseRelease",@comp.onMouseButton);
            ana.ui.input(comp.Draw,"KeyPress",@comp.onKeyPress);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.Draw.Position = [1 1 comp.Position(3:4)];
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 800 200];
            comp.Draw = uiaxes(comp);
            comp.Draw.XTick = [];
            comp.Draw.YTick = [];
            comp.Draw.ZTick = [];
            comp.Draw.Toolbar = [];
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end